classdef ComputerVisionTextDetection < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        UIAxes                    matlab.ui.control.UIAxes
        Label                     matlab.ui.control.Label
        TextDetectionButton       matlab.ui.control.Button
        ChooseImageDropDownLabel  matlab.ui.control.Label
        ChooseImageDropDown       matlab.ui.control.DropDown
        SaveTextButton            matlab.ui.control.Button
    end


    properties (Access = private)
        colorImage= imread('handicapSign.jpg');
        s=' '; 
         % Description
        
    end

    methods (Access = private)
    
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.colorImage= imread('handicapSign.jpg');  
            imshow(app.colorImage,'Parent',app.UIAxes);
        end

        % Button pushed function: TextDetectionButton
        function TextDetectionButtonPushed(app, event)
            I = rgb2gray(app.colorImage);
          
             [mserRegions, mserConnComp] = detectMSERFeatures(I, ...    
                 'RegionAreaRange',[200 8000],'ThresholdDelta',4);
           
             %imshow(I);
                         
             mserStats = regionprops(mserConnComp, 'BoundingBox', 'Eccentricity', ...
    'Solidity', 'Extent', 'Euler', 'Image');

% Compute the aspect ratio using bounding box data.
bbox = vertcat(mserStats.BoundingBox);
w = bbox(:,3);
h = bbox(:,4);
aspectRatio = w./h;

% Threshold the data to determine which regions to remove. These thresholds
% may need to be tuned for other images.
filterIdx = aspectRatio' > 3;
filterIdx = filterIdx | [mserStats.Eccentricity] > .995 ;
filterIdx = filterIdx | [mserStats.Solidity] < .3;
filterIdx = filterIdx | [mserStats.Extent] < 0.2 | [mserStats.Extent] > 0.9;
filterIdx = filterIdx | [mserStats.EulerNumber] < -4;

% Remove regions
mserStats(filterIdx) = [];
mserRegions(filterIdx) = [];


% Get a binary image of the a region, and pad it to avoid boundary effects
% during the stroke width computation.
regionImage = mserStats(6).Image;
regionImage = padarray(regionImage, [1 1]);

% Compute the stroke width image.
distanceImage = bwdist(~regionImage);
skeletonImage = bwmorph(regionImage, 'thin', inf);

strokeWidthImage = distanceImage;
strokeWidthImage(~skeletonImage) = 0;

% Compute the stroke width variation metric
strokeWidthValues = distanceImage(skeletonImage);
strokeWidthMetric = std(strokeWidthValues)/mean(strokeWidthValues);

% Threshold the stroke width variation metric
strokeWidthThreshold = 0.4;
strokeWidthFilterIdx = strokeWidthMetric > strokeWidthThreshold;

% Process the remaining regions
for j = 1:numel(mserStats)

    regionImage = mserStats(j).Image;
    regionImage = padarray(regionImage, [1 1], 0);

    distanceImage = bwdist(~regionImage);
    skeletonImage = bwmorph(regionImage, 'thin', inf);

    strokeWidthValues = distanceImage(skeletonImage);

    strokeWidthMetric = std(strokeWidthValues)/mean(strokeWidthValues);

    strokeWidthFilterIdx(j) = strokeWidthMetric > strokeWidthThreshold;

end

% Remove regions based on the stroke width variation
mserRegions(strokeWidthFilterIdx) = [];
mserStats(strokeWidthFilterIdx) = [];

% Show remaining regions

% Get bounding boxes for all the regions
bboxes = vertcat(mserStats.BoundingBox);

% Convert from the [x y width height] bounding box format to the [xmin ymin
% xmax ymax] format for convenience.
xmin = bboxes(:,1);
ymin = bboxes(:,2);
xmax = xmin + bboxes(:,3) - 1;
ymax = ymin + bboxes(:,4) - 1;

% Expand the bounding boxes by a small amount.
expansionAmount = 0.02;
xmin = (1-expansionAmount) * xmin;
ymin = (1-expansionAmount) * ymin;
xmax = (1+expansionAmount) * xmax;
ymax = (1+expansionAmount) * ymax;

% Clip the bounding boxes to be within the image bounds
xmin = max(xmin, 1);
ymin = max(ymin, 1);
xmax = min(xmax, size(I,2));
ymax = min(ymax, size(I,1));

% Show the expanded bounding boxes
expandedBBoxes = [xmin ymin xmax-xmin+1 ymax-ymin+1];
IExpandedBBoxes = insertShape(app.colorImage,'Rectangle',expandedBBoxes,'LineWidth',3);
% Compute the overlap ratio
overlapRatio = bboxOverlapRatio(expandedBBoxes, expandedBBoxes);

% Set the overlap ratio between a bounding box and itself to zero to
% simplify the graph representation.
n = size(overlapRatio,1);
overlapRatio(1:n+1:n^2) = 0;

% Create the graph
g = graph(overlapRatio);

% Find the connected text regions within the graph
componentIndices = conncomp(g);

% Merge the boxes based on the minimum and maximum dimensions.
xmin = accumarray(componentIndices', xmin, [], @min);
ymin = accumarray(componentIndices', ymin, [], @min);
xmax = accumarray(componentIndices', xmax, [], @max);
ymax = accumarray(componentIndices', ymax, [], @max);

% Compose the merged bounding boxes using the [x y width height] format.
textBBoxes = [xmin ymin xmax-xmin+1 ymax-ymin+1];

% Remove bounding boxes that only contain one text region
numRegionsInGroup = histcounts(componentIndices);
textBBoxes(numRegionsInGroup == 1, :) = [];

% Show the final text detection result.
ITextRegion = insertShape(app.colorImage, 'Rectangle', textBBoxes,'LineWidth',3);
%figure
%imshow(ITextRegion)
%title('Detected Text')
ocrtxt = ocr(I, textBBoxes);
[ocrtxt.Text]
app.Label.Text=ocrtxt.Text;
app.s=ocrtxt.Text;
f=msgbox(ocrtxt.Text,'Information','help');
        end

        % Value changed function: ChooseImageDropDown
        function ChooseImageDropDownValueChanged(app, event)
            value = app.ChooseImageDropDown.Value;
            if strcmp(value,'Handicap')
                app.colorImage = imread('handicapSign.jpg');
                imshow(app.colorImage,'Parent',app.UIAxes);
            elseif  strcmp(value,'Peppers')
                app.colorImage = imread('peppers.png');
                imshow(app.colorImage,'Parent',app.UIAxes);
            end
                
                
        end

        % Button pushed function: SaveTextButton
        function SaveTextButtonPushed(app, event)
            [file,path]=uiputfile('*.txt','Save your text ');
            f=strcat(path,file);
            s1=app.s;
            fid = fopen(f,'wt');
            fprintf(fid, '%s\n', s1 );
            fclose(fid);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Image')
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.TextDetectionButton = uibutton(app.UIFigure, 'push');
            app.TextDetectionButton.ButtonPushedFcn = createCallbackFcn(app, @TextDetectionButtonPushed, true);
            app.TextDetectionButton.Text = 'Text Detection';
            app.ChooseImageDropDownLabel = uilabel(app.UIFigure);
            app.ChooseImageDropDownLabel.Text = 'Choose Image';
            app.ChooseImageDropDown = uidropdown(app.UIFigure);
            app.ChooseImageDropDown.Items = {'Handicap', 'Peppers'};
            app.ChooseImageDropDown.ValueChangedFcn = createCallbackFcn(app, @ChooseImageDropDownValueChanged, true);
            app.ChooseImageDropDown.Value = 'Handicap';
            app.SaveTextButton = uibutton(app.UIFigure, 'push');
            app.SaveTextButton.ButtonPushedFcn = createCallbackFcn(app, @SaveTextButtonPushed, true);
            app.SaveTextButton.Text = 'Save Text';
        end
    end

    methods (Access = public)

        % Construct app
        function app = ComputerVisionTextDetection

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end