classdef Dropdown < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        SelectDropDownLabel  matlab.ui.control.Label
        SelectDropDown       matlab.ui.control.DropDown
        TabGroup             matlab.ui.container.TabGroup
        CreateTab            matlab.ui.container.Tab
        Label                matlab.ui.control.Label
        Label_3              matlab.ui.control.Label
        CreateButton         matlab.ui.control.Button
        AddTab               matlab.ui.container.Tab
        NameEditFieldLabel   matlab.ui.control.Label
        NameEditField        matlab.ui.control.EditField
        AgeEditFieldLabel    matlab.ui.control.Label
        AgeEditField         matlab.ui.control.NumericEditField
        GenderDropDownLabel  matlab.ui.control.Label
        GenderDropDown       matlab.ui.control.DropDown
        AddButton            matlab.ui.control.Button
        ViewTab              matlab.ui.container.Tab
        UITable              matlab.ui.control.Table
        DeleteButton         matlab.ui.control.Button
    end


    properties (Access = private)
        mytable % Description
        deInd = 0
    end


    methods (Access = private)

        % Value changed function: SelectDropDown
        function SelectDropDownValueChanged(app, event)
            mychoice= app.SelectDropDown.Value;
            switch mychoice
                case 'Create'
                    app.TabGroup.SelectedTab=app.CreateTab;
                case 'Add'
                    app.TabGroup.SelectedTab=app.AddTab;
                case 'Delete'
                    app.TabGroup.SelectedTab=app.DeleteTab;
            end
        end

        % Callback function
        function TriggerButtonPushed(app, event)
            
        end

        % Button pushed function: CreateButton
        function CreateButtonPushed(app, event)
            app.Label_3.Text='Database Created ';
            app.UITable.Data=cell(3,0);
            app.CreateButton.Enable='off';
        end

        % Button pushed function: AddButton
        function AddButtonPushed(app, event)
            app.mytable=app.UITable.Data;
            n=app.NameEditField;
            age=app.AgeEditField;
            gen=app.GenderDropDown.Value;
            app.mytable=vertcat(app.mytable,{n,age,gen});
            app.UITable.Data=app.mytable;
            msgbox('Data Added');
        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
            app.deInd=event.Indices;
        end

        % Button pushed function: DeleteButton
        function DeleteButtonPushed(app, event)
            app.UITable.Data(app.deInd(1),:)=[];
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.SelectDropDownLabel = uilabel(app.UIFigure);
            app.SelectDropDownLabel.Text = 'Select';
            app.SelectDropDown = uidropdown(app.UIFigure);
            app.SelectDropDown.Items = {'Create', 'Add', 'Delete'};
            app.SelectDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectDropDownValueChanged, true);
            app.SelectDropDown.Value = 'Create';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.CreateTab = uitab(app.TabGroup);
            app.CreateTab.Title = 'Create';
            app.Label = uilabel(app.CreateTab);
            app.Label.Text = '';
            app.Label_3 = uilabel(app.CreateTab);
            app.Label_3.Text = '';
            app.CreateButton = uibutton(app.CreateTab, 'push');
            app.CreateButton.ButtonPushedFcn = createCallbackFcn(app, @CreateButtonPushed, true);
            app.CreateButton.Text = 'Create';
            app.AddTab = uitab(app.TabGroup);
            app.AddTab.Title = 'Add';
            app.NameEditFieldLabel = uilabel(app.AddTab);
            app.NameEditFieldLabel.Text = 'Name';
            app.NameEditField = uieditfield(app.AddTab, 'text');
            app.AgeEditFieldLabel = uilabel(app.AddTab);
            app.AgeEditFieldLabel.Text = 'Age';
            app.AgeEditField = uieditfield(app.AddTab, 'numeric');
            app.GenderDropDownLabel = uilabel(app.AddTab);
            app.GenderDropDownLabel.Text = 'Gender';
            app.GenderDropDown = uidropdown(app.AddTab);
            app.GenderDropDown.Items = {'Male', 'Female'};
            app.GenderDropDown.Value = 'Male';
            app.AddButton = uibutton(app.AddTab, 'push');
            app.AddButton.ButtonPushedFcn = createCallbackFcn(app, @AddButtonPushed, true);
            app.AddButton.Text = 'Add';
            app.ViewTab = uitab(app.TabGroup);
            app.ViewTab.Title = 'View';
            app.UITable = uitable(app.ViewTab);
            app.UITable.ColumnName = {'Name'; 'Age'; 'Sex'};
            app.UITable.RowName = {};
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.DeleteButton = uibutton(app.ViewTab, 'push');
            app.DeleteButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteButtonPushed, true);
            app.DeleteButton.Text = 'Delete';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Dropdown

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end