classdef Entername_fontsize < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        EnternameEditFieldLabel  matlab.ui.control.Label
        Entername                matlab.ui.control.EditField
        ShowName                 matlab.ui.control.Button
        Label                    matlab.ui.control.Label
        IncreaseFontSizeButton   matlab.ui.control.Button
        DecreaseFontSizeButton   matlab.ui.control.Button
    end

    methods (Access = private)

        % Button pushed function: IncreaseFontSizeButton
        function IncreaseFontSizeButtonPushed(app, event)

            app.Label.FontSize=app.Label.FontSize+2;
            
        end

        % Button pushed function: ShowName
        function ShowNameButtonPushed(app, event)
            s=app.Entername.Value;
            app.IncreaseFontSizeButton.Enable='on';
            app.DecreaseFontSizeButton.Enable='on';
            app.Label.HandleVisibility='on';
            app.Label.Text=s;
        end

        % Button pushed function: DecreaseFontSizeButton
        function DecreaseFontSizeButtonPushed(app, event)
            app.Label.FontSize=app.Label.FontSize-2;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.EnternameEditFieldLabel = uilabel(app.UIFigure);
            app.EnternameEditFieldLabel.Text = 'Enter name';
            app.Entername = uieditfield(app.UIFigure, 'text');
            app.ShowName = uibutton(app.UIFigure, 'push');
            app.ShowName.ButtonPushedFcn = createCallbackFcn(app, @ShowNameButtonPushed, true);
            app.ShowName.Text = 'Show Name';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.IncreaseFontSizeButton = uibutton(app.UIFigure, 'push');
            app.IncreaseFontSizeButton.ButtonPushedFcn = createCallbackFcn(app, @IncreaseFontSizeButtonPushed, true);
            app.IncreaseFontSizeButton.Enable = 'off';
            app.IncreaseFontSizeButton.Text = 'Increase Font Size';
            app.DecreaseFontSizeButton = uibutton(app.UIFigure, 'push');
            app.DecreaseFontSizeButton.ButtonPushedFcn = createCallbackFcn(app, @DecreaseFontSizeButtonPushed, true);
            app.DecreaseFontSizeButton.Enable = 'off';
            app.DecreaseFontSizeButton.Text = 'Decrease Font Size';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Entername_fontsize

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end