classdef ImageProcessing < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        UIAxes                     matlab.ui.control.UIAxes
        RminEditFieldLabel         matlab.ui.control.Label
        RminEditField              matlab.ui.control.NumericEditField
        RmaxEditFieldLabel         matlab.ui.control.Label
        RmaxEditField              matlab.ui.control.NumericEditField
        CheckRadiusRangeButton     matlab.ui.control.Button
        FindCirclesButton          matlab.ui.control.Button
        ImageTypeButtonGroup       matlab.ui.container.ButtonGroup
        RGBButton                  matlab.ui.control.ToggleButton
        GrayscaleButton            matlab.ui.control.ToggleButton
        SensitivitySliderLabel     matlab.ui.control.Label
        SensitivitySlider          matlab.ui.control.Slider
        BackgroundtypeButtonGroup  matlab.ui.container.ButtonGroup
        brightButton               matlab.ui.control.RadioButton
        darkButton                 matlab.ui.control.RadioButton
    end


    properties (Access = private)
        myimage % Description
        backgroundtype='dark'
    end


    methods (Access = private)

        % Selection changed function: ImageTypeButtonGroup
        function ImageTypeButtonGroupSelectionChanged(app, event)
            selectedButton = app.ImageTypeButtonGroup.SelectedObject;
            switch selectedButton
                case 'Grayscale'
                    imshow(rgb2gray(app.myimage),'Parent',app.UIAxes);
                otherwise 
                    imshow(app.myimage,'Parent',app.UIAxes);
            end
        end

        % Selection changed function: BackgroundtypeButtonGroup
        function BackgroundtypeButtonGroupSelectionChanged(app, event)
            selectedButton = app.BackgroundtypeButtonGroup.SelectedObject.Text;
            switch selectedchoice
                case 'dark'
                    
        end

        % Button pushed function: CheckRadiusRangeButton
        function CheckRadiusRangeButtonPushed(app, event)
            figure
            imshow(app.myimage)
            d=imdistline
        end

        % Button pushed function: FindCirclesButton
        function FindCirclesButtonPushed(app, event)
            [centers,radii]=imfindcircles(app.myimage,[],'ObjectPolarity',app.backgroundtype,'Sensitivity');
            app.circlecount.Value=size(radii,1);
            fig=figure('Menubar','none','ToolBar','none','Resize','off','NumberTitle','off');
            imshow(app.myimage,'Border','Tight',fig);
            h=viscircles(centers,radii);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Image')
            app.RminEditFieldLabel = uilabel(app.UIFigure);
            app.RminEditFieldLabel.Text = 'Rmin';
            app.RminEditField = uieditfield(app.UIFigure, 'numeric');
            app.RmaxEditFieldLabel = uilabel(app.UIFigure);
            app.RmaxEditFieldLabel.Text = 'Rmax';
            app.RmaxEditField = uieditfield(app.UIFigure, 'numeric');
            app.CheckRadiusRangeButton = uibutton(app.UIFigure, 'push');
            app.CheckRadiusRangeButton.ButtonPushedFcn = createCallbackFcn(app, @CheckRadiusRangeButtonPushed, true);
            app.CheckRadiusRangeButton.Text = 'Check Radius Range';
            app.FindCirclesButton = uibutton(app.UIFigure, 'push');
            app.FindCirclesButton.ButtonPushedFcn = createCallbackFcn(app, @FindCirclesButtonPushed, true);
            app.FindCirclesButton.Text = 'Find Circles';
            app.ImageTypeButtonGroup = uibuttongroup(app.UIFigure);
            app.ImageTypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ImageTypeButtonGroupSelectionChanged, true);
            app.ImageTypeButtonGroup.Title = 'Image Type';
            app.RGBButton = uitogglebutton(app.ImageTypeButtonGroup);
            app.RGBButton.Text = 'RGB';
            app.RGBButton.Value = true;
            app.GrayscaleButton = uitogglebutton(app.ImageTypeButtonGroup);
            app.GrayscaleButton.Text = 'Grayscale';
            app.SensitivitySliderLabel = uilabel(app.UIFigure);
            app.SensitivitySliderLabel.Text = 'Sensitivity';
            app.SensitivitySlider = uislider(app.UIFigure);
            app.SensitivitySlider.Limits = [0.9 1];
            app.SensitivitySlider.Value = 0.9;
            app.BackgroundtypeButtonGroup = uibuttongroup(app.UIFigure);
            app.BackgroundtypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @BackgroundtypeButtonGroupSelectionChanged, true);
            app.BackgroundtypeButtonGroup.Title = 'Background type';
            app.brightButton = uiradiobutton(app.BackgroundtypeButtonGroup);
            app.brightButton.Text = 'bright';
            app.brightButton.Value = true;
            app.darkButton = uiradiobutton(app.BackgroundtypeButtonGroup);
            app.darkButton.Text = 'dark';
        end
    end

    methods (Access = public)

        % Construct app
        function app = ImageProcessing

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end