classdef RadioButton < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        ChangeFontSizeButton   matlab.ui.control.Button
        FontSizeButtonGroup    matlab.ui.container.ButtonGroup
        IncreaseButton         matlab.ui.control.RadioButton
        DecreaseButton         matlab.ui.control.RadioButton
        MITLabel               matlab.ui.control.Label
        FontSize10Label        matlab.ui.control.Label
        L10                    matlab.ui.control.Lamp
        FontSize1030LampLabel  matlab.ui.control.Label
        Lamp1030               matlab.ui.control.Lamp
        FontSize30LampLabel    matlab.ui.control.Label
        Lamp30                 matlab.ui.control.Lamp
        SliderLabel            matlab.ui.control.Label
        Slider                 matlab.ui.control.Slider
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.L10.Color=[1 1 1];
            app.Lamp1030.Color=[0 1 0];
            app.Lamp30.Color=[1 1 1];
        end

        % Button pushed function: ChangeFontSizeButton
        function ChangeFontSizeButtonPushed(app, event)
            selectedButton = app.FontSizeButtonGroup.SelectedObject.Text;
            
            if selectedButton=='Increase'
                app.MITLabel.FontSize=app.MITLabel.FontSize+2
            else
               app.MITLabel.FontSize=app.MITLabel.FontSize-2 
            end
            x=app.MITLabel.FontSize;
            
            if x<=10
               app.L10.Color=[0 1 0];
               app.Lamp1030.Color=[1 1 1];
               app.Lamp30.Color=[1 1 1];
            elseif x>30
               app.L10.Color=[1 1 1];
               app.Lamp1030.Color=[1 1 1];
               app.Lamp30.Color=[0 1 0];
            else
               app.L10.Color=[1 1 1];
               app.Lamp1030.Color=[0 1 0];
               app.Lamp30.Color=[1 1 1];
            end
            
        end

        % Selection changed function: FontSizeButtonGroup
        function FontSizeButtonGroupSelectionChanged(app, event)
            selectedButton = app.FontSizeButtonGroup.SelectedObject
            
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            value = app.Slider.Value;
            app.MITLabel.FontSize=value; 
            
        end

        % Value changing function: Slider
        function SliderValueChanging(app, event)
            changingValue = event.Value;
            app.MITLabel.FontSize=changingValue; 
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.ChangeFontSizeButton = uibutton(app.UIFigure, 'push');
            app.ChangeFontSizeButton.ButtonPushedFcn = createCallbackFcn(app, @ChangeFontSizeButtonPushed, true);
            app.ChangeFontSizeButton.Text = 'Change Font Size';
            app.FontSizeButtonGroup = uibuttongroup(app.UIFigure);
            app.FontSizeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @FontSizeButtonGroupSelectionChanged, true);
            app.FontSizeButtonGroup.Title = 'Font Size';
            app.IncreaseButton = uiradiobutton(app.FontSizeButtonGroup);
            app.IncreaseButton.Text = 'Increase';
            app.IncreaseButton.Value = true;
            app.DecreaseButton = uiradiobutton(app.FontSizeButtonGroup);
            app.DecreaseButton.Text = 'Decrease';
            app.MITLabel = uilabel(app.UIFigure);
            app.MITLabel.Text = 'MIT';
            app.FontSize10Label = uilabel(app.UIFigure);
            app.FontSize10Label.Text = 'Font Size <=10';
            app.L10 = uilamp(app.UIFigure);
            app.FontSize1030LampLabel = uilabel(app.UIFigure);
            app.FontSize1030LampLabel.Text = 'Font Size 10-30';
            app.Lamp1030 = uilamp(app.UIFigure);
            app.FontSize30LampLabel = uilabel(app.UIFigure);
            app.FontSize30LampLabel.Text = 'Font Size <=30';
            app.Lamp30 = uilamp(app.UIFigure);
            app.SliderLabel = uilabel(app.UIFigure);
            app.SliderLabel.Text = 'Slider';
            app.Slider = uislider(app.UIFigure);
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider.ValueChangingFcn = createCallbackFcn(app, @SliderValueChanging, true);
        end
    end

    methods (Access = public)

        % Construct app
        function app = RadioButton

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end