classdef RadioButtons < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        MITLabel             matlab.ui.control.Label
        SmallTextLampLabel   matlab.ui.control.Label
        SmallTextLamp        matlab.ui.control.Lamp
        MediumTextLampLabel  matlab.ui.control.Label
        MediumTextLamp       matlab.ui.control.Lamp
        BigTextLampLabel     matlab.ui.control.Label
        BigTextLamp          matlab.ui.control.Lamp
        TextButtonGroup      matlab.ui.container.ButtonGroup
        IncreaseSizeButton   matlab.ui.control.RadioButton
        DecreaseSizeButton   matlab.ui.control.RadioButton
        NoChangeButton       matlab.ui.control.RadioButton
        ChangeFontButton     matlab.ui.control.Button
        TextSizeSliderLabel  matlab.ui.control.Label
        TextSizeSlider       matlab.ui.control.Slider
    end

    methods (Access = private)

        % Value changing function: TextSizeSlider
        function TextSizeSliderValueChanging(app, event)
            changingValue = event.Value;
            if changingValue >0
            app.MITLabel.FontSize=changingValue;
            if app.MITLabel.FontSize>30
              app.SmallTextLamp.Color=[1 1 1];
              app.MediumTextLamp.Color=[1 1 1];
              app.BigTextLamp.Color=[1 1 0];
              
            elseif  app.MITLabel.FontSize>10
              app.SmallTextLamp.Color=[1 1 1];
              app.MediumTextLamp.Color=[1 1 0];
              app.BigTextLamp.Color=[1 1 1];
            
            else
              app.SmallTextLamp.Color=[1 1 0];
              app.MediumTextLamp.Color=[1 1 1];
              app.BigTextLamp.Color=[1 1 1];
            end
            end
        
        end

        % Selection changed function: TextButtonGroup
        function TextButtonGroupSelectionChanged(app, event)
            selectedButton = app.TextButtonGroup.SelectedObject.Text;
            
        end

        % Button pushed function: ChangeFontButton
        function ChangeFontButtonPushed(app, event)
            selectedButton = app.TextButtonGroup.SelectedObject.Text;
            if strcmp(selectedButton,'Increase Size')
            app.MITLabel.FontSize=app.MITLabel.FontSize+2;
            elseif strcmp(selectedButton,'Decrease Size')
                if app.MITLabel.FontSize>2
                app.MITLabel.FontSize=app.MITLabel.FontSize-2;
                end
                if app.MITLabel.FontSize>30
              app.SmallTextLamp.Color=[1 1 1];
              app.MediumTextLamp.Color=[1 1 1];
              app.BigTextLamp.Color=[1 1 0];
              
            elseif  app.MITLabel.FontSize>10
              app.SmallTextLamp.Color=[1 1 1];
              app.MediumTextLamp.Color=[1 1 0];
              app.BigTextLamp.Color=[1 1 1];
            
            else
              app.SmallTextLamp.Color=[1 1 0];
              app.MediumTextLamp.Color=[1 1 1];
              app.BigTextLamp.Color=[1 1 1];
            end
            end
        
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.MITLabel = uilabel(app.UIFigure);
            app.MITLabel.Text = 'MIT';
            app.SmallTextLampLabel = uilabel(app.UIFigure);
            app.SmallTextLampLabel.Text = 'Small Text';
            app.SmallTextLamp = uilamp(app.UIFigure);
            app.MediumTextLampLabel = uilabel(app.UIFigure);
            app.MediumTextLampLabel.Text = 'Medium Text';
            app.MediumTextLamp = uilamp(app.UIFigure);
            app.BigTextLampLabel = uilabel(app.UIFigure);
            app.BigTextLampLabel.Text = 'Big Text';
            app.BigTextLamp = uilamp(app.UIFigure);
            app.TextButtonGroup = uibuttongroup(app.UIFigure);
            app.TextButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @TextButtonGroupSelectionChanged, true);
            app.TextButtonGroup.Title = 'Text';
            app.IncreaseSizeButton = uiradiobutton(app.TextButtonGroup);
            app.IncreaseSizeButton.Text = 'Increase Size';
            app.IncreaseSizeButton.Value = true;
            app.DecreaseSizeButton = uiradiobutton(app.TextButtonGroup);
            app.DecreaseSizeButton.Text = 'Decrease Size';
            app.NoChangeButton = uiradiobutton(app.TextButtonGroup);
            app.NoChangeButton.Text = 'No Change';
            app.ChangeFontButton = uibutton(app.UIFigure, 'push');
            app.ChangeFontButton.ButtonPushedFcn = createCallbackFcn(app, @ChangeFontButtonPushed, true);
            app.ChangeFontButton.Text = 'Change Font';
            app.TextSizeSliderLabel = uilabel(app.UIFigure);
            app.TextSizeSliderLabel.Text = 'Text Size';
            app.TextSizeSlider = uislider(app.UIFigure);
            app.TextSizeSlider.ValueChangingFcn = createCallbackFcn(app, @TextSizeSliderValueChanging, true);
        end
    end

    methods (Access = public)

        % Construct app
        function app = RadioButtons

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end