classdef Voltage_Variation < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        PowerPanel            matlab.ui.container.Panel
        SwitchLabel           matlab.ui.control.Label
        Switch                matlab.ui.control.Switch
        IndicatorLampLabel    matlab.ui.control.Label
        IndicatorLamp         matlab.ui.control.Lamp
        ControlPanel          matlab.ui.container.Panel
        VoltageSliderLabel    matlab.ui.control.Label
        VoltageSlider         matlab.ui.control.Slider
        ResistanceKnobLabel   matlab.ui.control.Label
        ResistanceKnob        matlab.ui.control.Knob
        ResultsPanel          matlab.ui.container.Panel
        VoltagermsGaugeLabel  matlab.ui.control.Label
        VoltagermsGauge       matlab.ui.control.Gauge
        Label_2               matlab.ui.control.Label
        EditField             matlab.ui.control.NumericEditField
        UIAxes                matlab.ui.control.UIAxes
    end


    properties (Access = private)
        resistance=1;
        voltage=1;% Description
    end

    methods (Access = private)
    
        function myplot(app)
        t=0:0.01:10;
        v=app.voltage*sind(2*pi*50*t);
        plot(app.UIAxes,t,v);
        hold(app.UIAxes,'on')
        app.VoltagermsGauge.Value=app.voltage/sqrt(2);
        i=v/app.resistance;
        plot(app.UIAxes,t,i);
         hold(app.UIAxes,'off')
        legend(app.UIAxes,'Voltage','Current')
        end
        
    end


    methods (Access = private)

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            value = app.Switch.Value;
            switch value
                case 'On'
                    app.VoltageSlider.Enable='on'
                    app.IndicatorLamp.Color=[1 1 0]
                otherwise
                    app.VoltageSlider.Enable='off'
                    app.IndicatorLamp.Color=[.9 .9 .9];
            end
        end

        % Value changing function: VoltageSlider
        function VoltageSliderValueChanging(app, event)
            app.voltage = event.Value;
            myplot(app);
            
        end

        % Value changing function: ResistanceKnob
        function ResistanceKnobValueChanging(app, event)
           app.resistance = app.ResistanceKnob.Value;
            myplot(app);
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.PowerPanel = uipanel(app.UIFigure);
            app.PowerPanel.Title = 'Power';
            app.SwitchLabel = uilabel(app.PowerPanel);
            app.SwitchLabel.Text = 'Switch';
            app.Switch = uiswitch(app.PowerPanel, 'slider');
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.IndicatorLampLabel = uilabel(app.PowerPanel);
            app.IndicatorLampLabel.Text = 'Indicator';
            app.IndicatorLamp = uilamp(app.PowerPanel);
            app.ControlPanel = uipanel(app.UIFigure);
            app.ControlPanel.Title = 'Control';
            app.VoltageSliderLabel = uilabel(app.ControlPanel);
            app.VoltageSliderLabel.Enable = 'off';
            app.VoltageSliderLabel.Text = 'Voltage';
            app.VoltageSlider = uislider(app.ControlPanel);
            app.VoltageSlider.Limits = [0 10];
            app.VoltageSlider.MajorTickLabels = {'0', '2', '4', '6', '8', '10'};
            app.VoltageSlider.ValueChangingFcn = createCallbackFcn(app, @VoltageSliderValueChanging, true);
            app.VoltageSlider.Enable = 'off';
            app.ResistanceKnobLabel = uilabel(app.ControlPanel);
            app.ResistanceKnobLabel.Text = 'Resistance';
            app.ResistanceKnob = uiknob(app.ControlPanel, 'continuous');
            app.ResistanceKnob.Limits = [1 10];
            app.ResistanceKnob.ValueChangingFcn = createCallbackFcn(app, @ResistanceKnobValueChanging, true);
            app.ResistanceKnob.Value = 1;
            app.ResultsPanel = uipanel(app.UIFigure);
            app.ResultsPanel.Title = 'Results';
            app.VoltagermsGaugeLabel = uilabel(app.ResultsPanel);
            app.VoltagermsGaugeLabel.Text = 'Voltage(rms)';
            app.VoltagermsGauge = uigauge(app.ResultsPanel, 'circular');
            app.VoltagermsGauge.Limits = [0 10];
            app.Label_2 = uilabel(app.ResultsPanel);
            app.Label_2.Text = '';
            app.EditField = uieditfield(app.ResultsPanel, 'numeric');
            app.EditField.Editable = 'off';
            app.UIAxes = uiaxes(app.ResultsPanel);
            title(app.UIAxes, 'Waveforms')
            xlabel(app.UIAxes, 'Time')
            ylabel(app.UIAxes, 'Magnitude')
            app.UIAxes.YLim = [-10 10];
        end
    end

    methods (Access = public)

        % Construct app
        function app = Voltage_Variation

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end