classdef Voltage_Variationssss < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        PowerPanel           matlab.ui.container.Panel
        SwitchLabel          matlab.ui.control.Label
        Switch               matlab.ui.control.Switch
        IndicatorLampLabel   matlab.ui.control.Label
        IndicatorLamp        matlab.ui.control.Lamp
        Panel                matlab.ui.container.Panel
        UIAxes               matlab.ui.control.UIAxes
        VoltageGaugeLabel    matlab.ui.control.Label
        VoltageGauge         matlab.ui.control.Gauge
        Panel2               matlab.ui.container.Panel
        VoltageSliderLabel   matlab.ui.control.Label
        VoltageSlider        matlab.ui.control.Slider
        ResistanceKnobLabel  matlab.ui.control.Label
        ResistanceKnob       matlab.ui.control.Knob
    end


    properties (Access = private)
        voltage=1;
        r=1;% Description
    end

    methods (Access = private)
    
        function myplot(app)
            t=0:0.01:10;
            v=app.voltage*sind(2*pi*50*t);
            i=v/app.r;
            app.VoltageGauge.Value=app.voltage;
            plot(app.UIAxes,t,v);
            hold(app.UIAxes,'on')
            plot(app.UIAxes,t,i);
            hold(app.UIAxes,'off')
        end
        
    end


    methods (Access = private)

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            value = app.Switch.Value;
            if strcmp(app.Switch.Value,'On')
                app.IndicatorLamp.Color=[1 1 0];
                app.Panel2.Visible='on';
            else
                app.IndicatorLamp.Color=[1 1 1];
                app.Panel2.Visible='off';
            end
        end

        % Value changing function: VoltageSlider
        function VoltageSliderValueChanging(app, event)
            changingValue = event.Value;
            if changingValue >0
                app.voltage=changingValue;
                myplot(app);
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.PowerPanel = uipanel(app.UIFigure);
            app.PowerPanel.Title = 'Power';
            app.SwitchLabel = uilabel(app.PowerPanel);
            app.SwitchLabel.Text = 'Switch';
            app.Switch = uiswitch(app.PowerPanel, 'slider');
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.IndicatorLampLabel = uilabel(app.PowerPanel);
            app.IndicatorLampLabel.Text = 'Indicator';
            app.IndicatorLamp = uilamp(app.PowerPanel);
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Panel';
            app.UIAxes = uiaxes(app.Panel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'Time')
            ylabel(app.UIAxes, 'Voltage')
            app.VoltageGaugeLabel = uilabel(app.Panel);
            app.VoltageGaugeLabel.Text = 'Voltage';
            app.VoltageGauge = uigauge(app.Panel, 'circular');
            app.Panel2 = uipanel(app.UIFigure);
            app.Panel2.Title = 'Panel2';
            app.VoltageSliderLabel = uilabel(app.Panel2);
            app.VoltageSliderLabel.Text = 'Voltage';
            app.VoltageSlider = uislider(app.Panel2);
            app.VoltageSlider.ValueChangingFcn = createCallbackFcn(app, @VoltageSliderValueChanging, true);
            app.ResistanceKnobLabel = uilabel(app.Panel2);
            app.ResistanceKnobLabel.Text = 'Resistance';
            app.ResistanceKnob = uiknob(app.Panel2, 'continuous');
        end
    end

    methods (Access = public)

        % Construct app
        function app = Voltage_Variationssss

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end